classdef HCHPEXOv1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        HighLevelFrequencyEditFieldLabel  matlab.ui.control.Label
        edtFre              matlab.ui.control.NumericEditField
        ExpectRunningTimeEditFieldLabel  matlab.ui.control.Label
        edtRunTime          matlab.ui.control.NumericEditField
        HzLabel             matlab.ui.control.Label
        sLabel              matlab.ui.control.Label
        btnRun              matlab.ui.control.StateButton
        btnStop             matlab.ui.control.Button
        btnReset            matlab.ui.control.Button
        ProgramStatusLabel  matlab.ui.control.Label
        txtStatus           matlab.ui.control.TextArea
    end

    
    properties (Access = public)
        cellArrayTextStatus % Text to show running status of high-level program
    end
    
    methods (Access = public)
        
         function outPutStatus(app,str)
            string = sprintf('%s\n', str);  % Transfer input str to 'string'
            app.cellArrayTextStatus = horzcat(app.cellArrayTextStatus,string); % Serial connect the string array for display
            app.txtStatus.Value = app.cellArrayTextStatus;  % Set the display
         end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: btnRun
        function btnRunValueChanged(app, event)
            global ExoP;
            global TempApp;
            TempApp = app;
            value = app.btnRun.Value;
            if value == true
                ExoP = Set_Parameters();
                try                    
                    ExoP.MainFreq = get(app.edtFre,'value');
                    ExoP.MaxRunTime = get(app.edtRunTime,'value');
                catch
                    errordlg('Error in input');
                    return;
                end
                outPutStatus(app,'Program Start.');
                pause(5/1000);
                EXOmain();
            end
        end

        % Button pushed function: btnStop
        function btnStopButtonPushed(app, event)
            global ExoP;            
%             pause(5/1000);
            if(isvalid(ExoP.config{2,1}))
                stop(ExoP.config{2,1});
                outPutStatus(app,'Program Manually Stopped!');
            end            
        end

        % Button pushed function: btnReset
        function btnResetButtonPushed(app, event)
            app.cellArrayTextStatus = [];
            outPutStatus(app,'****Program Status****');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.HighLevelFrequencyEditFieldLabel = uilabel(app.UIFigure);
            app.HighLevelFrequencyEditFieldLabel.Text = 'High-Level Frequency';
            app.edtFre = uieditfield(app.UIFigure, 'numeric');
            app.edtFre.Limits = [0 200];
            app.ExpectRunningTimeEditFieldLabel = uilabel(app.UIFigure);
            app.ExpectRunningTimeEditFieldLabel.Text = 'Expect Running Time';
            app.edtRunTime = uieditfield(app.UIFigure, 'numeric');
            app.edtRunTime.Limits = [0 Inf];
            app.HzLabel = uilabel(app.UIFigure);
            app.HzLabel.Text = 'Hz';
            app.sLabel = uilabel(app.UIFigure);
            app.sLabel.Text = 's';
            app.btnRun = uibutton(app.UIFigure, 'state');
            app.btnRun.ValueChangedFcn = createCallbackFcn(app, @btnRunValueChanged, true);
            app.btnRun.Text = 'Run';
            app.btnStop = uibutton(app.UIFigure, 'push');
            app.btnStop.ButtonPushedFcn = createCallbackFcn(app, @btnStopButtonPushed, true);
            app.btnStop.Text = 'Stop';
            app.btnReset = uibutton(app.UIFigure, 'push');
            app.btnReset.ButtonPushedFcn = createCallbackFcn(app, @btnResetButtonPushed, true);
            app.btnReset.Text = 'RESET';
            app.ProgramStatusLabel = uilabel(app.UIFigure);
            app.ProgramStatusLabel.Text = 'Program Status';
            app.txtStatus = uitextarea(app.UIFigure);
            app.txtStatus.Editable = 'off';
            app.txtStatus.Value = {'****Program Status****'};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = HCHPEXOv1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end